classdef imganalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GIImgAnalysis              matlab.ui.Figure
        PolarCoordinatesLabel      matlab.ui.control.Label
        CartesianCoordinatesLabel  matlab.ui.control.Label
        text32_2                   matlab.ui.control.Label
        ed_zelongation             matlab.ui.control.EditField
        rb_ApplyMask               matlab.ui.control.CheckBox
        text38                     matlab.ui.control.Label
        ed_qatphi_cut              matlab.ui.control.Button
        text37                     matlab.ui.control.Label
        ed_phi                     matlab.ui.control.EditField
        text36                     matlab.ui.control.Label
        text35                     matlab.ui.control.Label
        ed_xpixel                  matlab.ui.control.EditField
        ed_ypixel                  matlab.ui.control.EditField
        pb_Vcut                    matlab.ui.control.Button
        pb_Hcut                    matlab.ui.control.Button
        text33                     matlab.ui.control.Label
        pm_Xlabel                  matlab.ui.control.DropDown
        pb_q_cut                   matlab.ui.control.Button
        text32                     matlab.ui.control.Label
        ed_q                       matlab.ui.control.EditField
        CB_add2plot                matlab.ui.control.CheckBox
        text31                     matlab.ui.control.Label
        ed_cutResolution           matlab.ui.control.EditField
        pb_qxy_Rcut                matlab.ui.control.Button
        pb_q_Rcut                  matlab.ui.control.Button
        pb_foldaxis                matlab.ui.control.Button
        pb_macrorun                matlab.ui.control.Button
        text30                     matlab.ui.control.Label
        ed_qr                      matlab.ui.control.EditField
        text29                     matlab.ui.control.Label
        ed_qt                      matlab.ui.control.EditField
        pb_q_Tcut                  matlab.ui.control.Button
        CB_foldXaxis               matlab.ui.control.CheckBox
        pm_plotscale               matlab.ui.control.DropDown
        ed_cutIndexNum             matlab.ui.control.EditField
        text28                     matlab.ui.control.Label
        text27                     matlab.ui.control.Label
        ed_figureNum               matlab.ui.control.EditField
        text26                     matlab.ui.control.Label
        ed_tth                     matlab.ui.control.EditField
        text25                     matlab.ui.control.Label
        ed_af                      matlab.ui.control.EditField
        text24                     matlab.ui.control.Label
        ed_qxy                     matlab.ui.control.EditField
        text23                     matlab.ui.control.Label
        ed_qrz                     matlab.ui.control.EditField
        text22                     matlab.ui.control.Label
        pb_afcut                   matlab.ui.control.Button
        pb_tthcut                  matlab.ui.control.Button
        pbGrid                     matlab.ui.control.Button
        pbImgAxisauto              matlab.ui.control.Button
        pbImgAxis                  matlab.ui.control.Button
        pb_loadmask                matlab.ui.control.Button
        pb_ClearLine               matlab.ui.control.Button
        pb_qrzcut                  matlab.ui.control.Button
        pb_qtzcut                  matlab.ui.control.Button
        pb_qxy_Tcut                matlab.ui.control.Button
        ed_qtz                     matlab.ui.control.EditField
        ed_DAng                    matlab.ui.control.EditField
        txtDeclineAngle            matlab.ui.control.Label
        txt_maskname               matlab.ui.control.Label
    end

    
    properties (Access = private)
        mask % Description
        tilt_angle = 0 % my data
        zelongation % my data 2
    end

    
    methods (Access = private)

        function dolinecut(app, saxs, P, cutdir)
            if ~app.rb_ApplyMask.Value
                saxs.mask = ones(size(saxs.image));
            else
                if ~isempty(app.txt_maskname.Text)
                    saxs.mask = app.mask;
                end
            end

            %if app.rb_cutResolution.Value
            bw = str2double(app.ed_cutResolution.Value);
            %else
            %    bw = 0;
            %end
            if strcmp(cutdir, 'phi')
                if bw~=0
                    errordlg("Cannot use linewidth for polar coordinates. Do this with SAXSavgerage.")
                    return
                end
                [q, th, Iq, px, py] = linecut_polar(saxs.image, P, saxs);
                cut.X = px;
                cut.Y = py;
                cut.th = th;
                cut.P = [px(:), py(:)];
                cut.axisofinterest = 'q';
                cut.q = q;
                cut.Iq = Iq;
                cutdir = [cutdir, num2str(P)];
            else
                if numel(saxs.tiltangle)==1
                    saxs.tiltangle = saxs.tiltangle + app.tilt_angle;
                else
                    saxs.tiltangle(3) = saxs.tiltangle(3) + app.tilt_angle;
                end
                if (bw~=0) && strcmp(cutdir, 'q')
                    errordlg("Cannot use linewidth for polar coordinates. Do this with SAXSavgerage.")
                    return
                end
                cut = linecut_q(saxs.image, P, cutdir, saxs, bw);
            end
            
            if isfield(cut, 'cutResolution')
                app.ed_cutResolution.Value = num2str(cut.cutResolution);
            end
            
            plotandsavecut(app, cut, cutdir, saxs)
        end
        
        function mat = rotmat2d(theta)
            %assert(isnumeric(theta)&&isscalar(theta),'Value must be a scalar numeric')
            mat = [cosd(theta),sind(theta);-sind(theta),cosd(theta)];
        end
        
        function pb_qcut(app, RorT)
            % hObject    handle to pb_q_Tcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            %setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qcut_Callback');
            zelong = 1;

            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            cnt = saxs.center;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            switch RorT
                case 'R'
                    rort = 1;
            %        rort = 2;
                    q = str2double(app.ed_qr.Value);
                    cutdir = 'azim2';
                case 'T'
                    rort = 2;
            %        rort = 1;
                    q = str2double(app.ed_qt.Value);
                    cutdir = 'azim1';
                case 'q'
            %        rort = 1;
                    rort = 2;
                    q = str2double(app.ed_q.Value);
                    %theta = str2double(app.ed_DAng.Value);
                    %rotm = [cosd(theta),sind(theta);-sind(theta),cosd(theta)];
                    cutdir = 'q';
                    if str2double(app.ed_zelongation.Value)~=1
                        zelong = str2double(app.ed_zelongation.Value);
                        th = linspace(0, 2*pi, 361);
                        % input tilt angle is applied.....
                        ta(3) = ta(3) + app.tilt_angle;
                        [x, y] = pol2cart(th, q);
                        y = y*zelong;
                        q = [x(:), y(:)];
                        %q = q*rotm;
                    end
            end
            
            % cutdir = 'azim1';
            % if isnan(q)
            %     q = str2double(app.ed_qr.Value);
            %     rort = 1;
            %     cutdir = 'azim2';
            % end
            if isnan(q)
                error('Type qz for eitehr qtz or qrz')
            end
            
            %q = [q, 0];
            %q = [q/sqrt(2), q/sqrt(2)];


            if RorT ~= 'q'
            %if size(q, 2)==2;
                P = q2pixel(q, wl, cnt, psize, sdd, ed, [], ai, ta);
            else
                if zelong == 1
                    P = q2pixel(q(:,1), wl, cnt, psize, sdd, ta);
                else
                    P = q2pixel(q, wl, cnt, psize, sdd, ta);
                end
            end
            if iscell(P)
                P = P{rort};
            end
            dolinecut(app, saxs, P, cutdir)
        end
        
        function pb_qxycut(app, RorT, varargin)
            cutdir = 'qxy';
            qxy = str2double(app.ed_qxy.Value);
            if isnan(qxy)
                qxy = 0;
            end
            
            switch RorT
                case 'R'
                    rort = 2;
            %        rort = 1;
                    qz = str2double(app.ed_qrz.Value);
                case 'T'
                    rort = 1;
            %        rort = 2;
                    qz = str2double(app.ed_qtz.Value);
            end
            % if isnan(qz)
            %     qz = str2double(app.ed_qrz.Value);
            %     rort = 1;
            % end
            if isnan(qz)
                error('Type qz for eitehr qtz or qrz')
            end
            
            q = [qxy, qz];
            
            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            cnt = saxs.center;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            
            % To include the declined angle in the imganalysis.fig.
            %ta(end) = ta(end) + str2double(app.ed_DAng.Value);
            
            P = q2pixel(q, wl, cnt, psize, sdd, ed, [], ai, ta);
            %[P, a, b, c] = q2pixel(q, wl, cnt, psize, sdd, ed, [], ai, ta), P{1};
            %P = pixel2angle(ang, saxs.center, sdd, psize, ta, 1);
            
            if iscell(P)
                P = P{rort};
            end
            if any(isnan(P))
                errordlg("The point is not on Bragg condition. Choose a different q_xy.")
                return
            end
            dolinecut(app, saxs, P, cutdir)
        end
        
        function plotandsavecut(app, cut, cutdir, saxs)
            IN = str2double(app.ed_cutIndexNum.Value);
            FN = saxs.imgname;
            cutname = sprintf('%s-%i-%s', cutdir, IN, FN);
            savefilename = sprintf('%s.dat', cutname);
            if exist(savefilename, 'file') == 2
                prompt = {'A cut is already made with the index number. Type new, otherwise the existing output file will be overwritten.'};
                dlg_title = 'New Index Number';
                num_lines = 1;
                defaultans = {num2str(IN)};
                x = inputdlg(prompt, dlg_title, num_lines, defaultans);
                data = str2double(x{1});
                if data ~= IN
                    app.ed_cutIndexNum.Value = num2str(data);
                    %IN = data;
                end
            end
            
            %t = cutname == '_';
            %cutname(t) = '-';
            op = plotoption;
            
            
            % When you select to plot....
            if app.CB_add2plot.Value
            % ----------------------------------------------------------
            % draw line on SAXSimageviewer...........
            % ----------------------------------------------------------
                hSAXSimageview = evalin('base', 'SAXSimagehandle');
                cut.handle = line(cut.X, cut.Y, 'parent', hSAXSimageview);
                if ~isfield(cut, 'cutResolution')
                    bw = 0;
                else
                    bw = cut.cutResolution;
                end
            
                switch cutdir
                    case {'h', 'qxy', 'tth', 'azim1', 'azim2', 'q'}
                        hLL = line(cut.X, cut.Y-bw, 'parent', hSAXSimageview);
                        hHL = line(cut.X, cut.Y+bw, 'parent', hSAXSimageview);
                    case {'v', 'qz1', 'qz2', 'v1', 'v2', 'af'}
                        hLL = line(cut.X-bw, cut.Y, 'parent', hSAXSimageview);
                        hHL = line(cut.X+bw, cut.Y, 'parent', hSAXSimageview);
                    otherwise
                        hLL = line(cut.X, cut.Y, 'parent', hSAXSimageview);
                        hHL = line(cut.X, cut.Y, 'parent', hSAXSimageview);
                end
                set(cut.handle, 'color', 'w', 'linestyle', '-');
                set(hLL, 'color', 'm', 'linestyle', '-');
                set(hHL, 'color', 'm', 'linestyle', '-');
                % ----------------------------------------------------------
                % plot data
                % ----------------------------------------------------------
                figN = str2double(app.ed_figureNum.Value);
                figure(figN);
                leg = get(figN, 'userdata');
                CNT = numel(leg);
                leg{CNT+1} = cutname;
                %ax = findobj(figN, 'type', 'axes');
                set(gca, 'nextplot', 'add')
            
                if (CNT>numel(op)-1)
                    CNT = 0;
                end
            
                %contents = cellstr(app.pm_Xlabel.Value);
                Xlabel = app.pm_Xlabel.Value;
            
                switch Xlabel
                    case 'Default'
                        x = cut.(cut.axisofinterest);
                    case 'q'
                        if isfield(cut, 'q')
                            x = cut.q;
                        else
                            x = sqrt(cut.qx.^2+cut.qy.^2+cut.qz.^2);
                        end
                    otherwise
                        x = cut.(Xlabel);
                end
            
                t = semilogy(x, cut.Iq, [op{CNT+1}{1}, op{CNT+1}{2}, '-']);
                set(t, 'tag', cutname);
                legend(leg);
                set(figN, 'userdata', leg);
                pm_plotscale_Callback(app, [])
            else
                cut.handle = [];
            end
            
            % ----------------------------------------------------------
            % file save...........................
            % ----------------------------------------------------------
            assignin('base', 'cut', cut);
            
            fid = fopen(savefilename,'w');
            fn = fieldnames(cut);
            fn([findcellstr(fn, cut.axisofinterest), ...
                findcellstr(fn, 'Iq'), findcellstr(fn, 'P'), ...
                findcellstr(fn, 'axisofinterest'), findcellstr(fn, 'handle')]) = [];
            t = zeros(numel(cut.(cut.axisofinterest)), 2+numel(fn));
            t(:,1) = cut.(cut.axisofinterest);
            t(:,2) = cut.Iq;
            titlestr = sprintf('%% %s %s', cut.axisofinterest, 'Iq');
            formatSpec = '%0.8f %0.3f';
            for i=1:numel(fn)
                if iscell(cut.(fn{i}))
                    fncell = cut.(fn{i});
                    t(:,2+i) = fncell{1};
                else
                    t(:,2+i) = cut.(fn{i});
                end
                titlestr = sprintf('%s %s', titlestr, fn{i});
                formatSpec = sprintf('%s %%0.8f', formatSpec);
            end
            formatSpec = sprintf('%s\n', formatSpec);
            fprintf(fid, '%s\n', titlestr);
            %for row = 1:nrows
            fprintf(fid,formatSpec,t');
            %end
            fclose(fid);
        end
        
        function savedatafile(app, cutname, data)
            % ----------------------------------------------------------
            % file save...........................
            % ----------------------------------------------------------
            
            fid = fopen(sprintf('%s-fold.dat', cutname),'w');
            formatSpec = '%0.8f %0.3f\n';
            %formatSpec = sprintf('%s\n', formatSpec);
            %for row = 1:nrows
            fprintf(fid,formatSpec,data');
            %end
            fclose(fid);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CB_foldXaxis
        function CB_foldXaxis_Callback(app, event)
            % --- Executes on button press in CB_foldXaxis.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_foldXaxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of CB_foldXaxis
            fn = str2double(app.ed_figureNum.Value);
            ax = findobj(fn, 'type', 'axes');
            if numel(ax) > 1 % when there the legend is on,
                axN = findcellstr(get(ax, 'tag'), '');
                ax = ax(axN);
            end
            dthandle = get(ax, 'children');
            for i=1:numel(dthandle)
                switch event.Value
                    case 1
                        xd = get(dthandle(i), 'xdata');
                        yd = get(dthandle(i), 'ydata');
                        dt = [xd(:), yd(:)];
                        [~,ind] = min(abs(xd));
                        xd_neg = xd(1:ind);
                        yd_neg = yd(1:ind);
                        xd_pos = xd(ind+1:end);
                        yd_pos = yd(ind+1:end);
                        set(dthandle(i), 'xdata', xd_pos, 'ydata', yd_pos);
                        newh = plot(ax, -xd_neg, yd_neg, ...
                            'linestyle', get(dthandle(i), 'linestyle'),...
                            'marker', get(dthandle(i), 'marker'),...
                            'color', 'r');
                        set(dthandle(i), 'userdata', {dt, newh});
                    case 0
                        dt0 = get(dthandle(i), 'userdata');
                        if ~isempty(dt0)
                            dt = dt0{1};
                            %delete(dt0{2})
                            xd = dt(:,1);
                            yd = dt(:,2);
                            set(dthandle(i), 'xdata', xd, 'ydata', yd);
            %            else
            %                delete(dthandle(i))
                        end
                        tg = get(dthandle(i), 'tag');
                        if isempty(tg)
                            delete(dthandle(i));
                        end
            
                        %set(dthandle(i), 'xdata', xd);
                end
            end
        end

        % Callback function: not associated with a component
        function ed_BandWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_BandWidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_BandWidth as text
            %        str2double(get(hObject,'String')) returns contents of ed_BandWidth as a double
            %saxs = getgihandle;
            %saxs.linecutbandwidth = str2double(event.Value);
            %setgihandle(saxs);
        end

        % Value changed function: ed_DAng
        function ed_DAng_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_BandWidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_BandWidth as text
            %        str2double(get(hObject,'String')) returns contents of ed_BandWidth as a double
            %saxs = getgihandle;
            %saxs.tiltangle = str2double(char(cellstr(app.ed_DAng.Value)));
            app.tilt_angle = str2double(event.Value);
            %setgihandle(saxs);
        end

        % Button pushed function: ed_qatphi_cut
        function ed_qatphi_cut_Callback(app, event)
            % --- Executes on button press in ed_qatphi_cut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
%            [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_qatphi_cut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qatphi_cut_Callback');
            saxs = getgihandle;
            P = str2double(app.ed_phi.Value);
            dolinecut(app, saxs, P, 'phi')
        end

        % Value changed function: ed_qr
        function ed_qr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_qr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: hObject,'String') returns contents of ed_qr as text
            %        str2double(hObject,'String')) returns contents of ed_qr as a double
            if ~isempty(app.ed_qr.Value)
                app.ed_qt.Value = '';
            end
        end

        % Value changed function: ed_qrz
        function ed_qrz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_qrz (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: hObject,'String') returns contents of ed_qrz as text
            %        str2double(hObject,'String')) returns contents of ed_qrz as a double
            if ~isempty(app.ed_qrz.Value)
                app.ed_qtz.Value = '';
            end
        end

        % Value changed function: ed_qt
        function ed_qt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_qt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: hObject,'String') returns contents of ed_qt as text
            %        str2double(hObject,'String')) returns contents of ed_qt as a double
            if ~isempty(app.ed_qt.Value)
                app.ed_qr.Value = '';
            end
        end

        % Value changed function: ed_qtz
        function ed_qtz_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if ~isempty(event.Value)
                app.ed_qrz.Value = '';
            end
        end

        % Button pushed function: pbGrid
        function pbGrid_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if strcmp(get(findobj(gcf, 'type', 'axes'),'xgrid'), 'on')
                set(findobj(gcf, 'Type', 'axes'), 'xgrid', 'off');
                set(findobj(gcf, 'Type', 'axes'), 'ygrid', 'off');
            else
                set(findobj(gcf, 'Type', 'axes'), 'xgrid', 'on');
                set(findobj(gcf, 'Type', 'axes'), 'ygrid', 'on');
            end
        end

        % Button pushed function: pbImgAxisauto
        function pbImgAxisauto_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            currentFig = gcf;
            set(findobj(gcf, 'Type', 'axes'), 'XTickLabelMode', 'auto');
            set(findobj(gcf, 'Type', 'axes'), 'YTickLabelMode', 'auto');
            set(findobj(gcf, 'Type', 'axes'), 'XTickMode', 'auto');
            set(findobj(gcf, 'Type', 'axes'), 'YTickMode', 'auto');
            set(findobj(gcf, 'Type', 'axes'), 'FontName', 'Helvetica');
            set(findobj(gcf, 'Type', 'axes'), 'FontSize', 10);
        end

        % Button pushed function: pb_ClearLine
        function pb_ClearLine_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            SAXSimagehandle = evalin('base', 'SAXSimagehandle');
            delete(findobj(get(SAXSimagehandle, 'children'), 'type', 'line'));
        end

        % Button pushed function: pb_Hcut
        function pb_Hcut_Callback(app, event)
            % --- Executes on button press in pb_Hcut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_Hcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qxy_Tcut_Callback');
            cutdir = 'h';
            
            saxs = getgihandle;
            ta = saxs.tiltangle;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            if isempty(app.ed_xpixel.Value)
                xv = 0;
            else
                xv = str2double(app.ed_xpixel.Value);
            end
            if isempty(app.ed_ypixel.Value)
                yv = 0;
            else
                yv = str2double(app.ed_ypixel.Value);
            end
            P = [xv, yv];
            dolinecut(app, saxs, P, cutdir)
        end

        % Button pushed function: pb_Vcut
        function pb_Vcut_Callback(app, event)
            % --- Executes on button press in pb_Vcut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_Vcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qxy_Tcut_Callback');
            cutdir = 'v';
            
            saxs = getgihandle;
            ta = saxs.tiltangle;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            if isempty(app.ed_xpixel.Value)
                xv = 0;
            else
                xv = str2double(app.ed_xpixel.Value);
            end
            if isempty(app.ed_ypixel.Value)
                yv = 0;
            else
                yv = str2double(app.ed_ypixel.Value);
            end

            P = [xv, yv];
            dolinecut(app, saxs, P, cutdir)
        end

        % Button pushed function: pb_afcut
        function pb_afcut_Callback(app, event)
            % --- Executes on button press in pb_afcut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_afcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_afcut_Callback');
            cutdir = 'af';
            tth = str2double(app.ed_tth.Value);
            %ang = [tth, 0];
            
            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            ang = [tth, ai];
            P = pixel2angle(ang, saxs.center, wl, sdd, psize, ta, 1);
            
            if iscell(P)
                P = P{2}; % 2 for transmitted beam...
            end
            P = P + saxs.center;
            dolinecut(app, saxs, P, cutdir)
        end

        % Button pushed function: pb_foldaxis
        function pb_foldaxis_Callback(app, event)
            % --- Executes on button press in pb_foldaxis.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_foldaxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            fn = str2double(app.ed_figureNum.Value);
            ax = findobj(fn, 'type', 'axes');
            if numel(ax) > 1 % when there the legend is on,
                axN = findcellstr(get(ax, 'tag'), '');
                ax = ax(axN);
            end
            dthandle = findobj(ax, 'type', 'line');
            folded = cellfun(@isempty, get(dthandle, 'userdata'));
            dthandle = dthandle(~folded);
            
            for i=1:numel(dthandle)
                xd = get(dthandle(i), 'xdata');
                yd = get(dthandle(i), 'ydata');
                tag = get(dthandle(i), 'tag');
                c1 = get(dthandle(i), 'color');
                % handle of folded...
                udata = get(dthandle(i), 'userdata');
                xd2 = get(udata{2}, 'xdata');
                yd2 = get(udata{2}, 'ydata');
                c2 = get(udata{2}, 'color');
                c = (c1+c2)/2;
                ydn = interp1(xd2, yd2, xd);
            % for adding nan and non-nan values...
                    nanyd = isnan(yd);
                    nanydn = isnan(ydn);
                    %bothnan = nonnanIq & nonnanny;
                    nanydnonydn = nanyd & ~nanydn;
                    nonydnanydn = ~nanyd & nanydn;
                    yd(nanydnonydn) = ydn(nanydnonydn);
                    ydn(nonydnanydn) = yd(nonydnanydn);
            
                ydn = (yd+ydn)/2;
                dt = [xd(:), ydn(:)];
                newh = plot(ax, dt(:,1), dt(:,2), ...
                    'linestyle', get(dthandle(i), 'linestyle'),...
                    'marker', get(dthandle(i), 'marker'),...
                    'color', c);
                savedatafile(app, tag, dt)
            end
        end

        % Button pushed function: pb_loadmask
        function pb_loadmask_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                prePath = pwd;
                % --- open file
                [filename, filepath] = uigetfile( ...
                    {'*.bmp;*.BMP','Mask Image (*.bmp, *.BMP)';'*.tiff;*.tif;*.TIF;*.TIFF','Mask Image (*.tif, *.tiff, *.TIF, *.TIFF)';'*.*','All Files (*.*)'}, ...
                    'Select Mask Image');
                % If "Cancel" is selected then return
                if isequal([filename,filepath],[0,0])
                    restorePath(prePath);
                    return
                end
                saxs = getgihandle;
                fullfilename = fullfile(filepath, filename);
            saxs.mask = double(imread(fullfilename));
            saxs.mask(saxs.mask < 1) = NaN;
            app.mask = saxs.mask;
            %setgihandle(saxs);
            app.txt_maskname.Text = fullfilename;
        end

        % Button pushed function: pb_macrorun
        function pb_macrorun_Callback(app, event)
            % --- Executes on button press in pb_macrorun.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_macrorun (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            funcname = getappdata(app.GIImgAnalysis, 'MostRecentCut');
            func = str2func(funcname);
            s = getgihandle;
            if isfield(s, 'dir')
                dirname = s.dir;
            else
                dirname = [];
            end
            % load image
            SAXSimageviewerhandle = evalin('base', 'SAXSimageviewerhandle');
            h = guiapp(SAXSimageviewerhandle);
            listoffile = h.FileListBox.Value;
            % run function
            Nf =numel(listoffile);
            for i=1:Nf
                filename = fullfile(dirname, listoffile{i});
                t = dir(filename);
                if ~isempty(t)
                    if ~t.isdir
                        SAXSimageviewer(filename);
                        func(app, event)
                        fprintf('Line cut done for %s, %i of %i\n', listoffile{i}, i, Nf)
                    end
                end
            end
        end

        % Button pushed function: pb_q_Rcut
        function pb_q_Rcut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%            [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_q_Tcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_q_Rcut_Callback');
            pb_qcut(app, 'R')
        end

        % Button pushed function: pb_q_Tcut
        function pb_q_Tcut_Callback(app, event)
            % --- Executes on button press in pb_q_Tcut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
%            [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_q_Tcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_q_Tcut_Callback');
            pb_qcut(app, 'T')
        end

        % Button pushed function: pb_q_cut
        function pb_q_cut_Callback(app, event)
            % --- Executes on button press in pb_q_cut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
%            [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_q_cut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_q_cut_Callback');
%            pb_qcut(app, 'q', hObject, eventdata)
            pb_qcut(app, 'q')

        end

        % Button pushed function: pb_qrzcut
        function pb_qrzcut_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qrzcut_Callback');
            cutdir = 'qz2';
            qxy = str2double(app.ed_qxy.Value);
            q = [qxy, 0];
            
            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            cnt = saxs.center;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            P = q2pixel(q, wl, cnt, psize, sdd, ed, [], ai, ta);
            if iscell(P)
            %    P = P{1}; % 2 for transmitted beam...
                P = P{2}; % 2 for transmitted beam...
            end
            dolinecut(app, saxs, P, cutdir)
        end

        % Button pushed function: pb_qtzcut
        function pb_qtzcut_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qtzcut_Callback');
            
            cutdir = 'qz1';
            qxy = str2double(app.ed_qxy.Value);
            q = [qxy, 0];
            
            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            cnt = saxs.center;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            P = q2pixel(q, wl, cnt, psize, sdd, ed, [], ai, ta);
            if iscell(P)
            %    P = P{2}; % 2 for transmitted beam...
                P = P{1}; % 2 for transmitted beam...
            end
            dolinecut(app, saxs, P, cutdir)
        end

        % Button pushed function: pb_qxy_Rcut
        function pb_qxy_Rcut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qxy_Rcut_Callback');
            pb_qxycut(app, 'R', event)
        end

        % Button pushed function: pb_qxy_Tcut
        function pb_qxy_Tcut_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_qxy_Tcut_Callback');
            pb_qxycut(app, 'T', event)
        end

        % Button pushed function: pb_tthcut
        function pb_tthcut_Callback(app, event)
            % --- Executes on button press in pb_tthcut.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_tthcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            setappdata(app.GIImgAnalysis, 'MostRecentCut', 'pb_tthcut_Callback');
            cutdir = 'tth';
            af = str2double(app.ed_af.Value);
            %ang = [0, af];
            
            saxs = getgihandle;
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            ed = saxs.edensity;
            ai = saxs.ai;
            ta = saxs.tiltangle;
            if numel(ta) == 1
                ta = [0,0,ta];
            end
            ang = [0, af+ai];
            P = pixel2angle(ang, saxs.center, wl, sdd, psize, ta, 1);
            if iscell(P)
                P = P{2}; % 2 for transmitted beam...
            end
            P = P + saxs.center;
            dolinecut(app, saxs, P, cutdir)
        end

        % Value changed function: pm_plotscale
        function pm_plotscale_Callback(app, event)
            % --- Executes on selection change in pm_plotscale.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pm_plotscale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns pm_plotscale contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pm_plotscale
            fn = str2double(app.ed_figureNum.Value);
            ax = findobj(fn, 'type', 'axes');
            if numel(ax)>1
                axN = findcellstr(get(ax, 'tag'), '');
                ax = ax(axN);
            end
            %contents = app.pm_plotscale.Value;
            str = app.pm_plotscale.Value;
            %indbar = find(str, '-');
            xs = str(1:3);
            ys = str(5:7);
            %switch get(hObject.Value
            %    case 'lin-lin'
            %    case 'lin-log'
            %    case 'log-lin'
            %    case 'log-log'
            %end
            set(ax, 'xscale', xs, 'yscale', ys)
        end

        % Value changed function: rb_ApplyMask
        function rb_ApplyMaskValueChanged(app, event)
            value = app.rb_ApplyMask.Value;
            if value
                if isempty(app.txt_maskname.Text)
                    fprintf('The mask loaded from Experiment Setup will be used.\n')
                else
                    fprintf('%s will be used as a mask.\n', app.txt_maskname.Text)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GIImgAnalysis = uifigure('Visible', 'off');
            colormap(app.GIImgAnalysis, 'parula');
            app.GIImgAnalysis.Name = 'Linecut Tool';
            app.GIImgAnalysis.Tag = 'GIImgAnalysis';
            app.txt_maskname = uilabel(app.GIImgAnalysis);
            app.txt_maskname.Tag = 'txt_maskname';
            app.txt_maskname.Text = '';
            app.txtDeclineAngle = uilabel(app.GIImgAnalysis);
            app.txtDeclineAngle.Tag = 'txtDeclineAngle';
            app.txtDeclineAngle.Text = 'Z direction';
            app.ed_DAng = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_DAng.ValueChangedFcn = createCallbackFcn(app, @ed_DAng_Callback, true);
            app.ed_DAng.Tag = 'ed_DAng';
            app.ed_DAng.Value = '0';
            app.ed_qtz = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_qtz.ValueChangedFcn = createCallbackFcn(app, @ed_qtz_Callback, true);
            app.ed_qtz.Tag = 'ed_qtz';
            app.pb_qxy_Tcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_qxy_Tcut.ButtonPushedFcn = createCallbackFcn(app, @pb_qxy_Tcut_Callback, true);
            app.pb_qxy_Tcut.Tag = 'pb_qxy_Tcut';
            app.pb_qxy_Tcut.Text = 'qxy cut';
            app.pb_qtzcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_qtzcut.ButtonPushedFcn = createCallbackFcn(app, @pb_qtzcut_Callback, true);
            app.pb_qtzcut.Tag = 'pb_qtzcut';
            app.pb_qtzcut.Text = 'q_{t,z} cut';
            app.pb_qrzcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_qrzcut.ButtonPushedFcn = createCallbackFcn(app, @pb_qrzcut_Callback, true);
            app.pb_qrzcut.Tag = 'pb_qrzcut';
            app.pb_qrzcut.Text = 'q_{r,z} cut';
            app.pb_ClearLine = uibutton(app.GIImgAnalysis, 'push');
            app.pb_ClearLine.ButtonPushedFcn = createCallbackFcn(app, @pb_ClearLine_Callback, true);
            app.pb_ClearLine.Tag = 'pb_ClearLine';
            app.pb_ClearLine.Text = 'Clear Line';
            app.pb_loadmask = uibutton(app.GIImgAnalysis, 'push');
            app.pb_loadmask.ButtonPushedFcn = createCallbackFcn(app, @pb_loadmask_Callback, true);
            app.pb_loadmask.Tag = 'pb_loadmask';
            app.pb_loadmask.Text = 'Load a mask file';
            app.pbImgAxis = uibutton(app.GIImgAnalysis, 'push');
            app.pbImgAxis.Tag = 'pbImgAxis';
            app.pbImgAxis.Text = 'Axis';
            app.pbImgAxisauto = uibutton(app.GIImgAnalysis, 'push');
            app.pbImgAxisauto.ButtonPushedFcn = createCallbackFcn(app, @pbImgAxisauto_Callback, true);
            app.pbImgAxisauto.Tag = 'pbImgAxisauto';
            app.pbImgAxisauto.Text = 'Axis auto';
            app.pbGrid = uibutton(app.GIImgAnalysis, 'push');
            app.pbGrid.ButtonPushedFcn = createCallbackFcn(app, @pbGrid_Callback, true);
            app.pbGrid.Tag = 'pbGrid';
            app.pbGrid.Text = 'Grid';
            app.pb_tthcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_tthcut.ButtonPushedFcn = createCallbackFcn(app, @pb_tthcut_Callback, true);
            app.pb_tthcut.Tag = 'pb_tthcut';
            app.pb_tthcut.Text = 'tth cut';
            app.pb_afcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_afcut.ButtonPushedFcn = createCallbackFcn(app, @pb_afcut_Callback, true);
            app.pb_afcut.Tag = 'pb_afcut';
            app.pb_afcut.Text = 'af cut';
            app.text22 = uilabel(app.GIImgAnalysis);
            app.text22.Tag = 'text22';
            app.text22.Text = 'q_{t,z}';
            app.ed_qrz = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_qrz.ValueChangedFcn = createCallbackFcn(app, @ed_qrz_Callback, true);
            app.ed_qrz.Tag = 'ed_qrz';
            app.text23 = uilabel(app.GIImgAnalysis);
            app.text23.Tag = 'text23';
            app.text23.Text = 'q_{r,z}';
            app.ed_qxy = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_qxy.Tag = 'ed_qxy';
            app.text24 = uilabel(app.GIImgAnalysis);
            app.text24.Tag = 'text24';
            app.text24.Text = 'q_xy';
            app.ed_af = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_af.Tag = 'ed_af';
            app.text25 = uilabel(app.GIImgAnalysis);
            app.text25.Tag = 'text25';
            app.text25.Text = 'af';
            app.ed_tth = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_tth.Tag = 'ed_tth';
            app.text26 = uilabel(app.GIImgAnalysis);
            app.text26.Tag = 'text26';
            app.text26.Text = 'tth';
            app.ed_figureNum = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_figureNum.Tag = 'ed_figureNum';
            app.ed_figureNum.Value = '1';
            app.text27 = uilabel(app.GIImgAnalysis);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Figure Number :';
            app.text28 = uilabel(app.GIImgAnalysis);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Lincut Index # :';
            app.ed_cutIndexNum = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_cutIndexNum.Tag = 'ed_cutIndexNum';
            app.ed_cutIndexNum.Value = '1';
            app.pm_plotscale = uidropdown(app.GIImgAnalysis);
            app.pm_plotscale.Items = {'lin-lin', 'lin-log', 'log-lin', 'log-log'};
            app.pm_plotscale.ValueChangedFcn = createCallbackFcn(app, @pm_plotscale_Callback, true);
            app.pm_plotscale.Tag = 'pm_plotscale';
            app.pm_plotscale.Value = 'lin-lin';
            app.CB_foldXaxis = uicheckbox(app.GIImgAnalysis);
            app.CB_foldXaxis.ValueChangedFcn = createCallbackFcn(app, @CB_foldXaxis_Callback, true);
            app.CB_foldXaxis.Tag = 'CB_foldXaxis';
            app.CB_foldXaxis.Text = 'Fold X axis';
            app.pb_q_Tcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_q_Tcut.ButtonPushedFcn = createCallbackFcn(app, @pb_q_Tcut_Callback, true);
            app.pb_q_Tcut.Tag = 'pb_q_Tcut';
            app.pb_q_Tcut.Text = 'q cut';
            app.ed_qt = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_qt.ValueChangedFcn = createCallbackFcn(app, @ed_qt_Callback, true);
            app.ed_qt.Tag = 'ed_qt';
            app.text29 = uilabel(app.GIImgAnalysis);
            app.text29.Tag = 'text29';
            app.text29.Text = 'qt';
            app.ed_qr = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_qr.ValueChangedFcn = createCallbackFcn(app, @ed_qr_Callback, true);
            app.ed_qr.Tag = 'ed_qr';
            app.text30 = uilabel(app.GIImgAnalysis);
            app.text30.Tag = 'text30';
            app.text30.Text = 'qr';
            app.pb_macrorun = uibutton(app.GIImgAnalysis, 'push');
            app.pb_macrorun.ButtonPushedFcn = createCallbackFcn(app, @pb_macrorun_Callback, true);
            app.pb_macrorun.Tag = 'pb_macrorun';
            app.pb_macrorun.Text = 'Macro Run';
            app.pb_foldaxis = uibutton(app.GIImgAnalysis, 'push');
            app.pb_foldaxis.ButtonPushedFcn = createCallbackFcn(app, @pb_foldaxis_Callback, true);
            app.pb_foldaxis.Tag = 'pb_foldaxis';
            app.pb_foldaxis.Text = 'Integrate +x/-x';
            app.pb_q_Rcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_q_Rcut.ButtonPushedFcn = createCallbackFcn(app, @pb_q_Rcut_Callback, true);
            app.pb_q_Rcut.Tag = 'pb_q_Rcut';
            app.pb_q_Rcut.Text = 'q cut';
            app.pb_qxy_Rcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_qxy_Rcut.ButtonPushedFcn = createCallbackFcn(app, @pb_qxy_Rcut_Callback, true);
            app.pb_qxy_Rcut.Tag = 'pb_qxy_Rcut';
            app.pb_qxy_Rcut.Text = 'qxy cut';
            app.ed_cutResolution = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_cutResolution.Tag = 'ed_cutResolution';
            app.ed_cutResolution.Value = '0';
            app.text31 = uilabel(app.GIImgAnalysis);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Linecut Half Width :';
            app.CB_add2plot = uicheckbox(app.GIImgAnalysis);
            app.CB_add2plot.Tag = 'CB_add2plot';
            app.CB_add2plot.Text = 'Add to plot';
            app.CB_add2plot.Value = true;
            app.ed_q = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_q.Tag = 'ed_q';
            app.text32 = uilabel(app.GIImgAnalysis);
            app.text32.Tag = 'text32';
            app.text32.Text = 'q(SAXS)';
            app.pb_q_cut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_q_cut.ButtonPushedFcn = createCallbackFcn(app, @pb_q_cut_Callback, true);
            app.pb_q_cut.Tag = 'pb_q_cut';
            app.pb_q_cut.Text = 'phi(Azm) cut';
            app.pm_Xlabel = uidropdown(app.GIImgAnalysis);
            app.pm_Xlabel.Items = {'Default', 'qxy', 'qy', 'qz', 'q', 'tthf', 'af', 'X', 'Y', 'phi'};
            app.pm_Xlabel.Tag = 'pm_Xlabel';
            app.pm_Xlabel.Value = 'Default';
            app.text33 = uilabel(app.GIImgAnalysis);
            app.text33.Tag = 'text33';
            app.text33.Text = 'X-axis Type :';
            app.pb_Hcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_Hcut.ButtonPushedFcn = createCallbackFcn(app, @pb_Hcut_Callback, true);
            app.pb_Hcut.Tag = 'pb_Hcut';
            app.pb_Hcut.Text = 'H cut';
            app.pb_Vcut = uibutton(app.GIImgAnalysis, 'push');
            app.pb_Vcut.ButtonPushedFcn = createCallbackFcn(app, @pb_Vcut_Callback, true);
            app.pb_Vcut.Tag = 'pb_Vcut';
            app.pb_Vcut.Text = 'V cut';
            app.ed_ypixel = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_ypixel.Tag = 'ed_ypixel';
            app.ed_xpixel = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_xpixel.Tag = 'ed_xpixel';
            app.text35 = uilabel(app.GIImgAnalysis);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Y pixel';
            app.text36 = uilabel(app.GIImgAnalysis);
            app.text36.Tag = 'text36';
            app.text36.Text = 'X pixel';
            app.ed_phi = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_phi.Tag = 'ed_phi';
            app.text37 = uilabel(app.GIImgAnalysis);
            app.text37.Tag = 'text37';
            app.text37.Text = 'phi(SAXS)';
            app.ed_qatphi_cut = uibutton(app.GIImgAnalysis, 'push');
            app.ed_qatphi_cut.ButtonPushedFcn = createCallbackFcn(app, @ed_qatphi_cut_Callback, true);
            app.ed_qatphi_cut.Tag = 'ed_qatphi_cut';
            app.ed_qatphi_cut.Text = 'q cut';
            app.text38 = uilabel(app.GIImgAnalysis);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Plot Type :';
            app.rb_ApplyMask = uicheckbox(app.GIImgAnalysis);
            app.rb_ApplyMask.ValueChangedFcn = createCallbackFcn(app, @rb_ApplyMaskValueChanged, true);
            app.rb_ApplyMask.Text = 'Apply Mask';
            app.ed_zelongation = uieditfield(app.GIImgAnalysis, 'text');
            app.ed_zelongation.Tag = 'ed_q';
            app.ed_zelongation.Value = '1';
            app.text32_2 = uilabel(app.GIImgAnalysis);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'Z elongation';
            app.CartesianCoordinatesLabel = uilabel(app.GIImgAnalysis);
            app.CartesianCoordinatesLabel.Text = 'Cartesian Coordinates:';
            app.PolarCoordinatesLabel = uilabel(app.GIImgAnalysis);
            app.PolarCoordinatesLabel.Text = 'Polar Coordinates:';
            % Show the figure after all components are created
            app.GIImgAnalysis.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imganalysis_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GIImgAnalysis)
            else

                % Focus the running singleton app
                figure(runningApp.GIImgAnalysis)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GIImgAnalysis)
        end
    end
end